classdef Panel_de_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OFFButton                    matlab.ui.control.Button
        ONButton                     matlab.ui.control.Button
        EndoWristControlPannelLabel  matlab.ui.control.Label
        SCISSORSSwitch               matlab.ui.control.Switch
        SCISSORSSwitchLabel          matlab.ui.control.Label
        PITCHButton_4                matlab.ui.control.Button
        PITCHButton_3                matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        YAWButton_2                  matlab.ui.control.Button
        YAWButton                    matlab.ui.control.Button
        ROLLButton_2                 matlab.ui.control.Button
        ROLLButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PITCHButton_4
        function PITCHButton_4Pushed(app, event)
            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'PITCH+';
            send(pub,msg);
        end

        % Button pushed function: PITCHButton_3
        function PITCHButton_3Pushed(app, event)
            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'PITCH-';
            send(pub,msg);
        end

        % Button pushed function: ROLLButton
        function ROLLButtonPushed(app, event)
            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'ROLL+';
            send(pub,msg);
        end

        % Button pushed function: ROLLButton_2
        function ROLLButton_2Pushed(app, event)
            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'ROLL-';
            send(pub,msg);
        end

        % Button pushed function: YAWButton
        function YAWButtonPushed(app, event)
            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'YAW+';
            send(pub,msg);
        end

        % Button pushed function: YAWButton_2
        function YAWButton_2Pushed(app, event)
            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'YAW-';
            send(pub,msg);
        end

        % Value changed function: SCISSORSSwitch
        function SCISSORSSwitchValueChanged(app, event)
            value = app.SCISSORSSwitch.Value;
            if value == 1

            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'OPEN';
            send(pub,msg);

            elseif value == 0

            pub = rospublisher('/cmd_pannel','std_msgs/String','DataFormat','struct');
            msg = rosmessage(pub);
            msg.Data = 'CLOSE';
            send(pub,msg);

            end
        end

        % Button pushed function: ONButton
        function ONButtonPushed(app, event)
            rosinit;
        end

        % Button pushed function: OFFButton
        function OFFButtonPushed(app, event)
            rosshutdown;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ROLLButton = uibutton(app.UIFigure, 'push');
            app.ROLLButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLButtonPushed, true);
            app.ROLLButton.Text = 'ROLL +';
            app.ROLLButton_2 = uibutton(app.UIFigure, 'push');
            app.ROLLButton_2.ButtonPushedFcn = createCallbackFcn(app, @ROLLButton_2Pushed, true);
            app.ROLLButton_2.Text = 'ROLL -';
            app.YAWButton = uibutton(app.UIFigure, 'push');
            app.YAWButton.ButtonPushedFcn = createCallbackFcn(app, @YAWButtonPushed, true);
            app.YAWButton.Text = 'YAW +';
            app.YAWButton_2 = uibutton(app.UIFigure, 'push');
            app.YAWButton_2.ButtonPushedFcn = createCallbackFcn(app, @YAWButton_2Pushed, true);
            app.YAWButton_2.Text = 'YAW';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMG_4842.jpg';
            app.PITCHButton_3 = uibutton(app.UIFigure, 'push');
            app.PITCHButton_3.ButtonPushedFcn = createCallbackFcn(app, @PITCHButton_3Pushed, true);
            app.PITCHButton_3.Text = 'PITCH -';
            app.PITCHButton_4 = uibutton(app.UIFigure, 'push');
            app.PITCHButton_4.ButtonPushedFcn = createCallbackFcn(app, @PITCHButton_4Pushed, true);
            app.PITCHButton_4.Text = 'PITCH +';
            app.SCISSORSSwitchLabel = uilabel(app.UIFigure);
            app.SCISSORSSwitchLabel.Text = 'SCISSORS';
            app.SCISSORSSwitch = uiswitch(app.UIFigure, 'slider');
            app.SCISSORSSwitch.Items = {'CLOSE', 'OPEN'};
            app.SCISSORSSwitch.ValueChangedFcn = createCallbackFcn(app, @SCISSORSSwitchValueChanged, true);
            app.SCISSORSSwitch.Value = 'CLOSE';
            app.EndoWristControlPannelLabel = uilabel(app.UIFigure);
            app.EndoWristControlPannelLabel.Text = 'EndoWrist Control Pannel';
            app.ONButton = uibutton(app.UIFigure, 'push');
            app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.ONButton.Text = 'ON';
            app.OFFButton = uibutton(app.UIFigure, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Panel_de_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end